classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AccuracyEditFieldLabel        matlab.ui.control.Label
        AccuracyEditField             matlab.ui.control.NumericEditField
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        TrainButton                   matlab.ui.control.Button
        TestButton                    matlab.ui.control.Button
        SamplingRateEditField_2Label  matlab.ui.control.Label
        SamplingRateEditField_2       matlab.ui.control.NumericEditField
        WindowWidthEditField_2Label   matlab.ui.control.Label
        WindowWidthEditField_2        matlab.ui.control.NumericEditField
        AccuracyGaugeLabel            matlab.ui.control.Label
        AccuracyGauge                 matlab.ui.control.Gauge
        ChoosetrainingfolderButton    matlab.ui.control.Button
        ChoosetestfolderButton        matlab.ui.control.Button
        TrainingdatafolderLabel       matlab.ui.control.Label
        TestdatafolderLabel           matlab.ui.control.Label
        ChoosemodelButton             matlab.ui.control.Button
        ModelfileLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        TrainDataPath='TrainingData' % Description
        TestDataPath='TestData'
        SamplingRate=50
        WindowWidth=3.4  %default data if no input 
        ModelName='Model.mat'
        Accuracy=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function TrainDataPathEditFieldValueChanging(app, event)
%             app.TrainDataPath = event.Value;
%             app.TrainDataPath=strcat(app.TrainDataPath,'\');
%             assignin("base",'TrainDataPath',app.TrainDataPath)    
        end

        % Callback function
        function TestDataPathEditFieldValueChanging(app, event)
%             app.TestDataPath = event.Value;
%             app.TestDataPath=strcat(app.TestDataPath,'\');
%             assignin("base",'TestDataPath',app.TestDataPath)
        end

        % Callback function
        function WindowWidthEditFieldValueChanging(app, event)
            app.WindowWidth = event.Value;
            assignin("base","WindowWidth",app.WindowWidth)
        end

        % Callback function
        function ModelEditFieldValueChanging(app, event)
%             app.ModelName = event.Value;
%             assignin("base","ModelName",app.ModelName)
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
%             selectedButton = app.ModeButtonGroup.SelectedObject;
%             switch selectedButton.Text
%                 case 'Train'
%                     train(app.TrainDataPath,app.SamplingRate,app.WindowWidth);
%                 case 'Test'
%                     app.Accuracy=classify(app.TestDataPath,app.ModelName,app.SamplingRate,app.WindowWidth);
%                     app.AccuracyEditField.Value=app.Accuracy;
%                     app.AccuracyGauge.Value=app.Accuracy*100;
%                     drawnow
%             end
        end

        % Value changed function: AccuracyEditField
        function AccuracyEditFieldValueChanged(app, event)
%             app.AccuracyEditField.Value=app.Accuracy;
%             app.AccuracyGauge.Value=app.Accuracy*100;
%             drawnow
        end

        % Value changed function: SamplingRateEditField_2
        function SamplingRateEditField_2ValueChanged(app, event)
            app.SamplingRate = event.Value;
            assignin("base","SamplingRate",app.SamplingRate)
        end

        % Value changed function: WindowWidthEditField_2
        function WindowWidthEditField_2ValueChanged(app, event)
            app.WindowWidth = event.Value;
            assignin("base","WindowWidth",app.WindowWidth)
        end

        % Button pushed function: ChoosetrainingfolderButton
        function ChoosetrainingfolderButtonPushed(app, event)
            app.TrainDataPath = uigetdir();
            assignin("base",'TrainDataPath',app.TrainDataPath)
        end

        % Button pushed function: ChoosetestfolderButton
        function ChoosetestfolderButtonPushed(app, event)
            app.TestDataPath = uigetdir();
            assignin("base",'TestDataPath',app.TestDataPath)
        end

        % Button pushed function: ChoosemodelButton
        function ChoosemodelButtonPushed(app, event)
            [app.ModelName, ~] = uigetfile('Model.mat');
            if strcmp(app.ModelName,'Model.mat')
                assignin("base","ModelName",app.ModelName)   
            else
                warndlg("Choose model file named 'Model.mat'");
            end
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.Accuracy=classify(app.TestDataPath,app.ModelName,app.SamplingRate,app.WindowWidth);
            app.AccuracyEditField.Value=app.Accuracy;
            app.AccuracyGauge.Value=app.Accuracy*100;
            drawnow
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            train(app.TrainDataPath,app.SamplingRate,app.WindowWidth);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AccuracyEditFieldLabel = uilabel(app.UIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccuracyEditField.ValueChangedFcn = createCallbackFcn(app, @AccuracyEditFieldValueChanged, true);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.TrainButton = uibutton(app.ModeButtonGroup, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.ModeButtonGroup, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.SamplingRateEditField_2Label = uilabel(app.UIFigure);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField_2.Limits = [0 Inf];
            app.SamplingRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditField_2ValueChanged, true);
            app.SamplingRateEditField_2.Value = 50;
            app.WindowWidthEditField_2Label = uilabel(app.UIFigure);
            app.WindowWidthEditField_2Label.Text = 'Window Width';
            app.WindowWidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WindowWidthEditField_2.Limits = [0 Inf];
            app.WindowWidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowWidthEditField_2ValueChanged, true);
            app.WindowWidthEditField_2.Value = 3.4;
            app.AccuracyGaugeLabel = uilabel(app.UIFigure);
            app.AccuracyGaugeLabel.Text = 'Accuracy';
            app.AccuracyGauge = uigauge(app.UIFigure, 'circular');
            app.ChoosetrainingfolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosetrainingfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetrainingfolderButtonPushed, true);
            app.ChoosetrainingfolderButton.Text = 'Choose training folder';
            app.ChoosetestfolderButton = uibutton(app.UIFigure, 'push');
            app.ChoosetestfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosetestfolderButtonPushed, true);
            app.ChoosetestfolderButton.Text = 'Choose test folder';
            app.TrainingdatafolderLabel = uilabel(app.UIFigure);
            app.TrainingdatafolderLabel.Text = 'Training data folder';
            app.TestdatafolderLabel = uilabel(app.UIFigure);
            app.TestdatafolderLabel.Text = 'Test data folder';
            app.ChoosemodelButton = uibutton(app.UIFigure, 'push');
            app.ChoosemodelButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosemodelButtonPushed, true);
            app.ChoosemodelButton.Text = 'Choose model';
            app.ModelfileLabel = uilabel(app.UIFigure);
            app.ModelfileLabel.Text = 'Model file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end